classdef TSP_Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure           % UI Figure
        Label              matlab.ui.control.Label    % Execution Time:
        Label2             matlab.ui.control.Label    % Tour length:
        Label3             matlab.ui.control.Label    % Tour:
        dataInput          matlab.ui.control.Button   % Data Input
        dataValidation     matlab.ui.control.Button   % Validate
        run                matlab.ui.control.Button   % Run
        lamp               matlab.ui.control.Lamp    
        tourPlot           matlab.ui.control.UIAxes   % Title
        LabelDropDown2     matlab.ui.control.Label    % Solving Mathod:
        algorithmSwitch    matlab.ui.control.DropDown % Brute Force, Intege...
        labelExecutionTime matlab.ui.control.Label    % Label
        Panel              matlab.ui.container.Panel  % Execution results
        labelTourLength    matlab.ui.control.Label    % Label
        labelTour          matlab.ui.control.Label    % Label
        outputField        matlab.ui.control.TextArea
    end

    methods (Access = public)
        function printToGui(app, text)
            app.outputField.Value{end+1} = text;
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.labelExecutionTime.Text = '';
            app.labelTourLength.Text = '';
            app.labelTour.Text = '';
            cla(app.tourPlot);
        end

        % run button pushed function
        function runPushed(app)
            switch app.algorithmSwitch.Value
                case 'Brute Force'
                    printToGui(app, 'Brute Force!');
                    
                case 'Integer Programming'
                    printToGui(app, 'Integer Programming!');
                    TSP_IntegerProgramm(app);
                    
                case 'Christofides'
                    printToGui(app, 'Christofides!');
                    
                case 'Opt2'
                    printToGui(app, '2-Opt!');
                    
                otherwise
                    printToGui(app, 'Error: please select a algorithm!');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.dataInput = uibutton(app.UIFigure, 'push');
            app.dataInput.Text = 'Data Input';
            app.dataValidation = uibutton(app.UIFigure, 'push');
            app.dataValidation.Text = 'Validate';
            app.run = uibutton(app.UIFigure, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @runPushed);
            app.run.Text = 'Run';
            app.lamp = uilamp(app.UIFigure);
            app.tourPlot = uiaxes(app.UIFigure);
            title(app.tourPlot, 'Title');
            xlabel(app.tourPlot, 'X');
            ylabel(app.tourPlot, 'Y');
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Solving Mathod:';
            app.algorithmSwitch = uidropdown(app.UIFigure);
            app.algorithmSwitch.Items = {'Brute Force', 'Integer Programming', 'Christofides', 'Opt2'};
            app.algorithmSwitch.Value = 'Brute Force';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Execution results';
            app.Panel.Units = 'pixels';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Execution Time:';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Tour length:';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Tour:';
            app.labelExecutionTime = uilabel(app.Panel);
            app.labelTourLength = uilabel(app.Panel);
            app.labelTour = uilabel(app.Panel);
            app.outputField = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = TSP_Gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end